classdef waveSim < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        l1Spinner         matlab.ui.control.Spinner
        Label             matlab.ui.control.Label
        p1Spinner         matlab.ui.control.Spinner
        Label_3           matlab.ui.control.Label
        x1Spinner         matlab.ui.control.Spinner
        x1Spinner_2Label  matlab.ui.control.Label
        y1Spinner         matlab.ui.control.Spinner
        y1Spinner_2Label  matlab.ui.control.Label
        l2Spinner         matlab.ui.control.Spinner
        Label_2           matlab.ui.control.Label
        p2Spinner         matlab.ui.control.Spinner
        Label_4           matlab.ui.control.Label
        x2Spinner         matlab.ui.control.Spinner
        x2SpinnerLabel    matlab.ui.control.Label
        y2Spinner         matlab.ui.control.Spinner
        RunButton         matlab.ui.control.Button
        ClearButton       matlab.ui.control.Button
        y2SpinnerLabel    matlab.ui.control.Label
        UIAxes            matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        run logical; % Description
        dt double = 0.5;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.run = true;
            l1 = app.l1Spinner.Value;
            p1 = app.p1Spinner.Value;
            x1 = app.x1Spinner.Value;
            y1 = app.y1Spinner.Value;

            l2 = app.l2Spinner.Value;
            p2 = app.p2Spinner.Value;
            x2 = app.x2Spinner.Value;
            y2 = app.y2Spinner.Value;

            container = Container(900,500);
            source1 = Source(container, l1, [x1;y1], p1);
            source2 = Source(container, l2, [x2;y2], p2);


            [~,~,Z1] = source1.D(0);
            [~,~,Z2] = source2.D(0);
            
            plt = app.UIAxes; 
            hold on;
            xlim(plt, [-450 450]); ylim(plt, [-250 250]); axis(plt, 'equal');
            
            style = [[1;(linspace(0,1,50))';(linspace(1,1,50))';0.5] [0;(linspace(0.7,1,50))';(linspace(1,1,50))';0] [0;(linspace(1,1,50))';(linspace(1,0,50))';1]];
            
            % h = pcolor(X,Y,Z1+Z2, 'FaceColor','interp', 'LineStyle','none'); colormap(style);
            h = imagesc(plt, [-450 450],[-250 250],Z1+Z2); colormap(style);
            t = 0;
            while app.run
                [~,~,Z1] = source1.D(t);
                [~,~,Z2] = source2.D(t);
                % h.ZData = Z1+Z2;
                h.CData = Z1+Z2;
                drawnow limitrate;

                t = t+app.dt;
            end
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.run = false;
            cla(app.UIAxes);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.y2SpinnerLabel = uilabel(app.UIFigure);
            app.y2SpinnerLabel.Text = '∆y 2';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.y2Spinner = uispinner(app.UIFigure);
            app.y2Spinner.Step = 0.1;
            app.y2Spinner.Limits = [-250 250];
            app.x2SpinnerLabel = uilabel(app.UIFigure);
            app.x2SpinnerLabel.Text = '∆x 2';
            app.x2Spinner = uispinner(app.UIFigure);
            app.x2Spinner.Step = 0.1;
            app.x2Spinner.Limits = [-450 450];
            app.x2Spinner.Value = 30;
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = 'φ 2';
            app.p2Spinner = uispinner(app.UIFigure);
            app.p2Spinner.Step = 0.01;
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'λ 2';
            app.l2Spinner = uispinner(app.UIFigure);
            app.l2Spinner.Step = 0.1;
            app.l2Spinner.Limits = [0.1 Inf];
            app.l2Spinner.Value = 40;
            app.y1Spinner_2Label = uilabel(app.UIFigure);
            app.y1Spinner_2Label.Text = '∆y 1';
            app.y1Spinner = uispinner(app.UIFigure);
            app.y1Spinner.Step = 0.1;
            app.y1Spinner.Limits = [-250 250];
            app.x1Spinner_2Label = uilabel(app.UIFigure);
            app.x1Spinner_2Label.Text = '∆x 1';
            app.x1Spinner = uispinner(app.UIFigure);
            app.x1Spinner.Step = 0.1;
            app.x1Spinner.Limits = [-450 450];
            app.x1Spinner.Value = -30;
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = 'φ 1';
            app.p1Spinner = uispinner(app.UIFigure);
            app.p1Spinner.Step = 0.01;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'λ 1';
            app.l1Spinner = uispinner(app.UIFigure);
            app.l1Spinner.Step = 0.1;
            app.l1Spinner.Limits = [0.1 Inf];
            app.l1Spinner.Value = 40;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = waveSim

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end